classdef ImageProcessingApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        HelpButton                matlab.ui.control.Button
        SaveImageButton           matlab.ui.control.Button
        ProcessButton             matlab.ui.control.Button
        SelectButton              matlab.ui.control.Button
        OpenImageButton           matlab.ui.control.Button
        Parameter2EditField       matlab.ui.control.NumericEditField
        Parameter2EditFieldLabel  matlab.ui.control.Label
        InputParameter            matlab.ui.control.NumericEditField
        ParameterLabel            matlab.ui.control.Label
        FilterDropDown            matlab.ui.control.DropDown
        FilterDropDownLabel       matlab.ui.control.Label
        Image                     matlab.ui.control.Image
        UIAxes2                   matlab.ui.control.UIAxes
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        img
        img2
        kernelSize
        noiseMean
        lastSelectedFilter
        filterChanged = false; % Flag to track if the filter changed
        selectedFilter='';
        imageChanged = true;
    end
    
    %Sude Özer 20191701023
    %Ayşe Nuriye Yılmaz 20191701025
    %Esin Can Bozyurt 20191701058
    
    methods (Access = private)

        function processedImage = applyHistogramEqualization(~,image)
            % Check if the image is RGB or grayscale
            if size(image, 3) == 3
                % Convert to grayscale if it's an RGB image
                grayImage = rgb2gray(image);
            else
                % Image is already grayscale
                grayImage = image;
            end
    
            % Get the size of the image
            [rows, cols] = size(grayImage);
    
            % Compute the histogram
            histogram = imhist(grayImage) / (rows * cols);
    
            % Compute the cumulative distribution function (CDF) of the histogram
            cdf = cumsum(histogram);
    
            % Scale CDF to intensity range [0, 1]
            cdfScaled = round(cdf * 255);
    
            % Apply histogram equalization
            processedImage = zeros(rows, cols, 'uint8');
            for i = 1:rows
                for j = 1:cols
                    pixelValue = grayImage(i, j) + 1; % MATLAB indices start from 1
                    processedImage(i, j) = cdfScaled(pixelValue);
                end
            end
        end
        
        function processedImage = applyMeanFiltering(~,image, kernelSize)
            % Check if the image is RGB or grayscale
            if size(image, 3) == 3
                % Convert to grayscale if it's an RGB image
                grayImage = rgb2gray(image);
            else
                % Image is already grayscale
                grayImage = image;
            end
    
            % Set a default kernel size if not provided or not valid
            if nargin < 2 || (~isnumeric(kernelSize) || ~isscalar(kernelSize) || kernelSize <= 0)
                kernelSize = 3; % Default size
            end
    
            [rows, cols] = size(grayImage);
    
            % Initialize the processed image
            processedImage = zeros(rows, cols, 'uint8');
    
            % Define the padding size for the image
            padSize = floor(kernelSize / 2);
    
            % Pad the image with zeros
            paddedImage = padarray(grayImage, [padSize, padSize], 0, 'both');
    
            % Apply mean filtering
            for i = 1:rows
                for j = 1:cols
                    % Extract the neighborhood
                    neighborhood = paddedImage(i:i+kernelSize-1, j:j+kernelSize-1);
    
                    % Compute the mean value
                    meanValue = mean(neighborhood, 'all');
    
                    % Assign the mean value to the corresponding pixel
                    processedImage(i, j) = uint8(meanValue);
                end
            end
        end
      
        function processedImage = applyMedianFiltering(~, image, kernelSize)
            
            % Check if kernelSize is valid
            if kernelSize <= 0 
                error('Kernel size must be a positive odd number.');
            end
            % Convert the image to double for processing
            image = double(image);
        
            % Get the size of the image
            [rows, cols] = size(image);
        
            % Calculate the padding size based on the kernel size
            padSize = floor(kernelSize / 2);
        
            % Pad the image
            paddedImage = padarray(image, [padSize, padSize], 'replicate');
        
            % Initialize the processed image
            processedImage = zeros(rows, cols);
        
            % Apply median filtering
            for i = 1:rows
                for j = 1:cols
                    % Extract the neighborhood around the current pixel
                    neighborhood = paddedImage(i:i+kernelSize-1, j:j+kernelSize-1);
        
                    % Flatten the neighborhood to a 1D array
                    neighborhood = neighborhood(:);
        
                    % Sort the neighborhood values
                    sortedNeighborhood = sort(neighborhood);
        
                    % Calculate the index of the middle element
                    middleIndex = floor(numel(sortedNeighborhood) / 2) + 1;
        
                    % Assign the middle element to the processed image
                    processedImage(i, j) = sortedNeighborhood(middleIndex);
                end
            end
          % Convert the processed image back to the original data type
            processedImage = uint8(processedImage);
        end

        function processedImage = applyAdaptiveMedianFiltering(~,image, maxSize)
            % Check if kernelSize is valid
            if maxSize <= 0 
                error('Kernel size must be a positive odd number.');
                return;
            end
            % Check if the image is RGB or grayscale
            if size(image, 3) == 3
                % Convert to grayscale if it's an RGB image
                grayImage = rgb2gray(image);
            else
                % Image is already grayscale
                grayImage = image;
            end
    
            % Get the size of the image
            [rows, cols] = size(grayImage);
    
            % Initialize the processed image
            processedImage = zeros(rows, cols, 'uint8');
    
            % Define the padding size for the image
            padSize = floor(maxSize / 2);
    
            % Pad the image with zeros
            paddedImage = padarray(grayImage, [padSize, padSize], 0, 'both');
    
            % Apply adaptive median filtering
            for i = 1:rows
                for j = 1:cols
                    % Extract the neighborhood
                    neighborhood = paddedImage(i:i+maxSize-1, j:j+maxSize-1);
    
                    % Reshape the neighborhood to a 1D array
                    neighborhood = reshape(neighborhood, 1, []);
    
                    % Sort the values
                    sortedValues = sort(neighborhood);
    
                    % Compute the median value
                    medianValue = sortedValues(floor(numel(sortedValues) / 2) + 1);
    
                    % Compute the local maximum and minimum
                    localMin = min(sortedValues);
                    localMax = max(sortedValues);
    
                    % Check if the median value is an impulse (salt or pepper noise)
                    if medianValue == localMin || medianValue == localMax
                        % If yes, set the pixel value to the local median
                        processedImage(i, j) = uint8((localMin + localMax) / 2);
                    else
                        % Otherwise, set the pixel value to the median value
                        processedImage(i, j) = uint8(medianValue);
                    end
                end
            end
        end

        function processedImage = applyGaussianFiltering(~,inputImage, sigma)
            if  avg < 0
                error('stdDev and avg should be 0 or greater.');
                return;
            end
            % Define the size of the kernel based on the standard deviation
            kSize = ceil(sigma * 6);
            if mod(kSize, 2) == 0
                kSize = kSize + 1;
            end
        
            % Create a grid of (x, y) coordinates centered at (0, 0)
            [x, y] = meshgrid(-floor(kSize/2):floor(kSize/2), -floor(kSize/2):floor(kSize/2));
        
            % Calculate the Gaussian kernel
            gaussianKernel = exp(-(x.^2 + y.^2) / (2 * sigma^2));
        
            % Normalize the kernel
            gaussianKernel = gaussianKernel / sum(gaussianKernel(:));
        
            % Apply the filter to the input image
            processedImage = imfilter(inputImage, gaussianKernel, 'conv');
        end

        function processedImage = applyUniformNoise(~, img, a, b)
            if a < 0 || a > 255 || b < 0 || b > 255 || a >= b
                error('a and b should be between 0 and 255, and a should be less than b.');
                return;
            end
            % 'rand' tarafından üretilen gürültü matrisini 'double' türüne dönüştür
            noise = double((b - a) * rand(size(img))) + a;
            
            % 'img' matrisini 'double' türüne dönüştür (eğer zaten double değilse)
            img = double(img);
            
            % Gürültüyü ekleyin
            processedImage = img + noise;
            
            % Gürültüyü sınırlar arasında kısıtlayın
            processedImage(processedImage < 0) = 0;
            processedImage(processedImage > 255) = 255;
            
            % Gürültü eklenmiş görüntüyü orijinal veri türüne geri dönüştürün
            processedImage = cast(processedImage, class(img));
        end

        function processedImage = applyAdditiveGaussianNoise(~, img, avg ,stdDev)  
            if stdDev < 0 || avg < 0
                error('stdDev and avg should be 0 or greater.');
                return;
            end
            % Gaussian gürültü oluştur
            noise = avg + sqrt(stdDev) * randn(size(img));
        
            % Gürültüyü ekleyin
            processedImage = double(img) + noise;
        
            % Gürültüyü sınırlar arasında kısıtlayın
            processedImage(processedImage < 0) = 0;
            processedImage(processedImage > 255) = 255;

        end

        function processedImage = applyAdditiveSaltAndPepperNoise(~, img, a, b)
            if a + b > 1 
                error('The sum of parameter1 and parameter2 cannot be greater than one.'); 
                return;
            end

            % Convert the image to double for compatibility
            processedImage = double(img);
        
            % Generate random matrix with uniform distribution
            X = rand(size(processedImage));
        
            % Set pixel values to 0 where X is less than or equal to 'a'
            processedImage(X <= a) = 0;
        
            % Set pixel values to 255 where X is greater than 'a' and less than or equal to 'a + b'
            u = a + b;
            processedImage(X > a & X <= u) = 255;
        
            % Convert back to uint8 for display
            processedImage = uint8(processedImage);
        end

        function processedImage = applyAdditiveLogNormalNoise(~, img, a,b)
            if a <= 0 || b <= 0
                error('a and b should be positive.');
                return;
            end
            img = double(img);
            
            % Generate lognormal noise
            noise = a * exp(b * randn(size(img)));
        
            % Add the noise to the image
            processedImage = img + noise;
        
            % Clip values to the valid range [0, 255]
            processedImage(processedImage < 0) = 0;
            processedImage(processedImage> 255) = 255;
        
            % Convert back to uint8 for display
            processedImage = uint8(processedImage);
        end

        function processedImage = applyAdditiveRayleighNoise(~, img, avg, skaler)
            if a <= 0 || b <= 0
                    error('a and b should be positive.');
                    return;
            end


            noise = avg + (-skaler * log(1 - rand(size(img, 1), size(img, 2)))) .^ 0.5;
        
            processedImage = double(img) + noise;
        end

        function processedImage = applyAdditiveExponentialNoise(~, img, a)
            k = -1 / a;
            noise = k * log(1 - rand(size(img)));
            if a < 0 
                error('The value of parameter must be positive.'); 
                return;
            end
            processedImage = double(img) + noise;

            processedImage(processedImage < 0) = 0;
            processedImage(processedImage > 255) = 255;
            processedImage = uint8(processedImage);
        end

        function processedImage = applyAdditiveErlangNoise(~, img, a, b)
            k = -1 / a;
            R = zeros(size(img));
            if b < 0 
              error('The value of positive parameter must be positive.'); 
              return;
            end
    
            for j = 1:b
                noise = k * log(1 - rand(size(img)));
    
                R = R + noise;
            end

            processedImage = double(img) + R;
            processedImage(processedImage < 0) = 0;
            processedImage(processedImage > 255) = 255;
            processedImage = uint8(processedImage);
        end

        function saveImage(app, image)
            % Prompt the user if they want to save the image
            saveChoice = uiconfirm(app.UIFigure, 'Do you want to save the processed image?', 'Save Confirmation', ...
                'Options', {'Yes', 'No'}, 'DefaultOption', 'Yes');
        
            % Process user's choice
            switch saveChoice
                case 'Yes'
                    % Display a progress dialog during the save operation
                    progressDialog = uiprogressdlg(app.UIFigure, 'Title', 'Saving Image', 'Message', 'Image is being saved...', 'Indeterminate', 'on', 'Cancelable', 'on');
        
                    try
                        % Simulate a 2-second delay (replace this line with your actual save code)
                        pause(2);
        
                        % Prompt user to choose a save path and enter a filename
                        [fileName, savePath] = uiputfile({'*.pgm', 'PGM Files'}, 'Save Processed Image As', 'processed_image.pgm');
        
                        % Check if the user clicked Cancel
                        if ~isequal(fileName, 0)
                            % Construct the full save path
                            fullFilePath = fullfile(savePath, fileName);
        
                            % Save the image
                            imwrite(image, fullFilePath);
        
                            % Display a success message
                            msgbox(['Image saved successfully: ' fullFilePath], 'Success', 'modal');
                        else
                            % User canceled the save operation
                            disp('Save operation canceled.');
                        end
                    catch ME
                        % Handle any errors that might occur during the save operation
                        delete(progressDialog);
                        rethrow(ME);
                    end
        
                    % Close the progress dialog
                    delete(progressDialog);
        
                case 'No'
                    % User chose not to save, do nothing
                    disp('Save operation canceled.');
            end
        end

        function onFilterValueChanged(app, ~, ~)
            currentFilter = app.selectedFilter;
            
            if ~strcmp(currentFilter, app.lastSelectedFilter)
                app.filterChanged = true;
            end
            
            app.lastSelectedFilter = currentFilter;
        end
    end   

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OpenImageButton
        function OpenImage(app, event)

            app.imageChanged = true;
            % Define valid image extensions
            validExtensions = {'.pgm'};
            app.FilterDropDown.Value = app.FilterDropDown.Items{1}; % Assuming the default item is the first one
            app.InputParameter.Editable = false;
            app.Parameter2EditField.Editable = false;
 
            % Continue with the rest of the code
            [fileName, filePath] = uigetfile({'*.png;*.jpg;*.jpeg;*.bmp;*.gif;*.pgm', 'Supported Image Files'}, 'Select an Image File');
            
            if fileName == 0 
                figure(app.UIFigure);
                return;
            end 
            
            % Get file extension from the selected file
            [~, ~, ext] = fileparts(fileName);
            
            % Check if the extension is in the list of valid extensions
            if ~ismember(lower(ext), validExtensions)
                % Generate a dynamic error message
                validExtensionsStr = strjoin(validExtensions, ', ');
                errorMsg = sprintf('Alert!', validExtensionsStr);
            
                % Display a warning using uialert
                uialert(app.UIFigure, 'Please select an image with the following extensions: %pgm.', errorMsg, 'Icon', 'warning');
                return;
            end
            
            % Read the selected image with a progress dialog
            progressDialog = uiprogressdlg(app.UIFigure, 'Title', 'Loading Image', 'Message', 'Image is loading...', 'Indeterminate', 'on', 'Cancelable', 'on');
            
            try
                % Load the image (replace this line with your image loading code)
                app.img = imread(fullfile(filePath, fileName));
            
                % Check if the image is already grayscale
                if size(app.img, 3) == 1
                    % Image is already grayscale, no need to convert
                    colorImage = app.img;
                else
                    % Image is not grayscale, convert to grayscale
                    I = rgb2gray(app.img);
                    colorImage = cat(3, I, I, I);
                end
            
                % Wait for 2 seconds
                pause(2);
            
                % Close the progress dialog
                delete(progressDialog);
            
                % Check if app.img is not empty before displaying
                if ~isempty(app.img)
                    % Display the image on the UIAxes
                    imshow(colorImage, 'parent', app.UIAxes);
                else
                    disp('No image selected.');
                end
            catch ME
                % Handle any errors that might occur during image loading
                delete(progressDialog);
                rethrow(ME);
            end


        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
                app.filterChanged = false;
                app.InputParameter.Editable = false;
                app.Parameter2EditField.Editable = false;
            
                % Get the selected filter from the dropdown
                app.selectedFilter = app.FilterDropDown.Value;
                % Manually call the value change event handler
                app.onFilterValueChanged();

             
                switch app.selectedFilter
                    case 'Histogram Equalization'                
                        app.ParameterLabel.Visible = 'off';             
                        app.InputParameter.Visible = 'off';
                        app.Parameter2EditField.Visible  = 'off';
                        app.Parameter2EditFieldLabel.Visible = 'off';


                    case 'Mean Filtering'
                        app.InputParameter.Editable = true;
                        app.InputParameter.Value = 0;
                        app.ParameterLabel.Text = 'Kernel Size';
                        app.ParameterLabel.Visible = 'on';                
                        app.InputParameter.Visible = 'on';
                        app.Parameter2EditFieldLabel.Visible = 'off';
                        app.Parameter2EditField.Visible  = 'off';

                       
                    case 'Median Filtering'
                        app.InputParameter.Editable = true;
                        app.InputParameter.Value = 0;
                        app.ParameterLabel.Text = 'Kernel Size';
                        app.Parameter2EditFieldLabel.Visible = 'off';
                        app.Parameter2EditField.Visible  = 'off';
                        app.ParameterLabel.Visible = 'on';                
                        app.InputParameter.Visible = 'on';
        
                    case 'Adaptive Median Filtering'
                        app.InputParameter.Editable = true;
                        app.InputParameter.Value = 0;
                        app.ParameterLabel.Text = 'Max Size';
                        app.Parameter2EditFieldLabel.Visible = 'off';
                        app.Parameter2EditField.Visible  = 'off';
                        app.ParameterLabel.Visible = 'on';                
                        app.InputParameter.Visible = 'on';
        
                    case 'Gaussian Filtering'
                        app.InputParameter.Editable = true;
                        app.InputParameter.Value = 0;
                        app.ParameterLabel.Text = "Std Deviation";
                        app.Parameter2EditFieldLabel.Visible = 'off';
                        app.Parameter2EditField.Visible  = 'off';
                        app.ParameterLabel.Visible = 'on';                
                        app.InputParameter.Visible = 'on';

                    case 'Uniform Noise'
                        app.InputParameter.Editable = true;
                        app.InputParameter.Value = 0;
                        app.ParameterLabel.Text = "Lower Bound";
                        app.Parameter2EditField.Value = 1;
                        app.Parameter2EditField.Editable = true;
                        app.Parameter2EditFieldLabel.Text = "Upper Bound";     
                        app.ParameterLabel.Visible = 'on';            
                        app.InputParameter.Visible = 'on';
                        app.Parameter2EditField.Visible  = 'on';
                        app.Parameter2EditFieldLabel.Visible = 'on';

                    case 'Additive Gaussian Noise'
                        app.InputParameter.Value = 0;
                        app.Parameter2EditField.Value = 1;
                        app.InputParameter.Editable = true;
                        app.Parameter2EditField.Editable = true;
                        app.ParameterLabel.Text = "Mean";
                        app.Parameter2EditFieldLabel.Text = "Std Deviation";
                        app.ParameterLabel.Visible = 'on';            
                        app.InputParameter.Visible = 'on';
                        app.Parameter2EditField.Visible  = 'on';
                        app.Parameter2EditFieldLabel.Visible = 'on';

                    case 'Salt & Pepper Noise'
                        app.InputParameter.Value = 0.05;
                        app.Parameter2EditField.Value = 0.05;
                        app.ParameterLabel.Text = "White Density";
                        app.Parameter2EditFieldLabel.Text = "Black Density";
                        app.InputParameter.Editable = true;
                        app.Parameter2EditField.Editable = true;
                        app.ParameterLabel.Visible = 'on';            
                        app.InputParameter.Visible = 'on';
                        app.Parameter2EditField.Visible  = 'on';
                        app.Parameter2EditFieldLabel.Visible = 'on';
        
                    case 'LogNormal Noise'
                        app.InputParameter.Value = 1;
                        app.Parameter2EditField.Value = 0.25;
                        app.ParameterLabel.Text = "Offset";
                        app.Parameter2EditFieldLabel.Text = "Shape Parameter";
                        app.InputParameter.Editable = true;
                        app.Parameter2EditField.Editable = true;

                        app.ParameterLabel.Visible = 'on';            
                        app.InputParameter.Visible = 'on';
                        app.Parameter2EditField.Visible  = 'on';
                        app.Parameter2EditFieldLabel.Visible = 'on';
        
                    case 'Rayleigh Noise'
                        app.InputParameter.Editable = true;
                        app.Parameter2EditField.Editable = true;
                        app.InputParameter.Value = 0;
                        app.Parameter2EditField.Value = 1;
                        app.ParameterLabel.Text= "Parameter 1";
                        app.Parameter2EditFieldLabel.Text= "Parameter 2";
                        app.ParameterLabel.Visible = 'on';            
                        app.InputParameter.Visible = 'on';
                        app.Parameter2EditField.Visible  = 'on';
                        app.Parameter2EditFieldLabel.Visible = 'on';
                       
        
                    case 'Exponential Noise'
                        app.ParameterLabel.Text= "Exp Number";
                        app.InputParameter.Value = 1;
                        app.InputParameter.Editable = true;
                        app.Parameter2EditFieldLabel.Visible = 'off';
                        app.Parameter2EditField.Visible  = 'off';
                        app.ParameterLabel.Visible = 'on';            
                        app.InputParameter.Visible = 'on';
                 
        
                    case 'Erlang Noise'
                        app.InputParameter.Editable = true;
                        app.Parameter2EditField.Editable = true;
                        app.ParameterLabel.Text= "Parameter";
                        app.Parameter2EditFieldLabel.Text= "Pos Parameter";
                        app.InputParameter.Value = 2;
                        app.Parameter2EditField.Value = 5;
                        app.ParameterLabel.Visible = 'on';            
                        app.InputParameter.Visible = 'on';
                        app.Parameter2EditField.Visible  = 'on';
                        app.Parameter2EditFieldLabel.Visible = 'on';
                end
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            app.kernelSize = app.InputParameter.Value;
            app.noiseMean = app.Parameter2EditField.Value;
         
            app.FilterDropDown.ValueChangedFcn = createCallbackFcn(app, @onFilterValueChanged, true);
    
            % Display a progress dialog during image processing
            progressDialog = uiprogressdlg(app.UIFigure, 'Title', 'Processing Image', 'Message', 'Image is being processed...', 'Indeterminate', 'on', 'Cancelable', 'on');
    
            % Introduce a 2-second delay for all cases
            pause(1);
    
            if ~app.filterChanged
                % Display a message indicating that no new filter is selected
                disp('No new filter selected. Process operation canceled.');
                return;
            end
 

            % Use the selected filter name to determine the appropriate case
            switch app.selectedFilter
                case 'Histogram Equalization'
                    app.img2 = app.applyHistogramEqualization(app.img);
    
                case 'Mean Filtering'
                    app.img2 = app.applyMeanFiltering(app.img, app.kernelSize);
    
                case 'Median Filtering'
                    app.img2 = app.applyMedianFiltering(app.img, app.kernelSize);
    
                case 'Adaptive Median Filtering'
                    app.img2 = app.applyAdaptiveMedianFiltering(app.img, app.kernelSize);
    
                case 'Gaussian Filtering'
                    app.img2 = app.applyGaussianFiltering(app.img, app.kernelSize); 
                    
                case 'Uniform Noise'
                    a = app.kernelSize;  % Minimum değer
                    b = app.noiseMean;  % Maksimum değer  
                    app.img2 = app.applyUniformNoise(app.img,a, b);
                    
                case 'Additive Gaussian Noise'
                    avg = app.kernelSize;
                    stdDev =app.noiseMean; % Assuming variance is used for standard deviation
                    app.img2 = app.applyAdditiveGaussianNoise(app.img, avg, stdDev);
                
                case 'Salt & Pepper Noise' 
                    a = app.kernelSize;% Pepper probability
                    b = app.noiseMean; % Salt probability
                    app.img2 = app.applyAdditiveSaltAndPepperNoise(app.img, a, b)

                case 'LogNormal Noise'
                    a = app.kernelSize;% Offset
                    b = app.noiseMean; % Shape parameter
                    app.img2 = app.applyAdditiveLogNormalNoise(app.img, a,b);
        
                case 'Rayleigh Noise'
                    a = app.kernelSize;% avg
                    b = app.noiseMean; % skaler
                    app.img2 = app.applyAdditiveRayleighNoise(app.img,a,b);
        
                case 'Exponential Noise'
                    a = app.kernelSize;% avg
                    app.img2 = app.applyAdditiveExponentialNoise(app.img,a);
        
                case 'Erlang Noise'
                    a = app.kernelSize;% avg
                    b = app.noiseMean; % skaler
                    app.img2 = app.applyAdditiveErlangNoise(app.img, a,b);

            end
    
            delete(progressDialog); 
            delete(progressDialog);

            if ~isempty(app.img2)
                % Check if img2 is an RGB image
                if size(app.img2, 3) == 3
                    % img2 is an RGB image, convert it to grayscale
                    app.img2 = rgb2gray(app.img2);
                end
                
                % Display the processed image on UIAxes2
                imshow(uint8(app.img2), 'Parent', app.UIAxes2);
                
            else
                disp('No processed image available.');
            end

        end

        % Button pushed function: SaveImageButton
        function SaveImage(app, event)
            app.saveImage(app.img2);
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            web('file:///Users/sudeozer/Desktop/cmpe490.html');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original Image')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = 0;
            app.UIAxes.Visible = 'off';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Filtered Image')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = 0;
            app.UIAxes2.Visible = 'off';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'WhatsApp Image 2023-11-28 at 15.40.36_preview_rev_1.png');
            app.FilterDropDownLabel = uilabel(app.UIFigure);
            app.FilterDropDownLabel.Text = 'Filter';
            app.FilterDropDown = uidropdown(app.UIFigure);
            app.FilterDropDown.Items = {'Histogram Equalization', 'Mean Filtering', 'Median Filtering', 'Adaptive Median Filtering', 'Gaussian Filtering', 'Uniform Noise', 'Additive Gaussian Noise', 'Salt & Pepper Noise', 'LogNormal Noise', 'Rayleigh Noise', 'Exponential Noise', 'Erlang Noise'};
            app.FilterDropDown.Value = 'Histogram Equalization';
            app.ParameterLabel = uilabel(app.UIFigure);
            app.ParameterLabel.Text = 'Parameter 1';
            app.InputParameter = uieditfield(app.UIFigure, 'numeric');
            app.InputParameter.Editable = 'off';
            app.Parameter2EditFieldLabel = uilabel(app.UIFigure);
            app.Parameter2EditFieldLabel.Text = 'Parameter 2';
            app.Parameter2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Parameter2EditField.Editable = 'off';
            app.OpenImageButton = uibutton(app.UIFigure, 'push');
            app.OpenImageButton.ButtonPushedFcn = createCallbackFcn(app, @OpenImage, true);
            app.OpenImageButton.Text = 'Open Image';
            app.SelectButton = uibutton(app.UIFigure, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.ProcessButton = uibutton(app.UIFigure, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.SaveImageButton = uibutton(app.UIFigure, 'push');
            app.SaveImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveImage, true);
            app.SaveImageButton.Text = 'Save Image';
            app.HelpButton = uibutton(app.UIFigure, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImageProcessingApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end