classdef Group9 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        nEditField_3                   matlab.ui.control.NumericEditField
        StdLabel                       matlab.ui.control.Label
        StdEditField_2                 matlab.ui.control.NumericEditField
        nLabel                         matlab.ui.control.Label
        Image3_5                       matlab.ui.control.Image
        Image3_4                       matlab.ui.control.Image
        Image4_4                       matlab.ui.control.Image
        Image4_3                       matlab.ui.control.Image
        Image5_28                      matlab.ui.control.Image
        Image5_27                      matlab.ui.control.Image
        Image5_26                      matlab.ui.control.Image
        Image5_25                      matlab.ui.control.Image
        Image5_24                      matlab.ui.control.Image
        Image5_23                      matlab.ui.control.Image
        Image5_22                      matlab.ui.control.Image
        Image5_21                      matlab.ui.control.Image
        Image3_3                       matlab.ui.control.Image
        Image5_20                      matlab.ui.control.Image
        Image5_19                      matlab.ui.control.Image
        Image5_18                      matlab.ui.control.Image
        Image5_17                      matlab.ui.control.Image
        Image5_16                      matlab.ui.control.Image
        Image5_15                      matlab.ui.control.Image
        Image5_14                      matlab.ui.control.Image
        Image5_13                      matlab.ui.control.Image
        Image2_4                       matlab.ui.control.Image
        Image3_2                       matlab.ui.control.Image
        Image2_2                       matlab.ui.control.Image
        Image4_2                       matlab.ui.control.Image
        Image5_12                      matlab.ui.control.Image
        Image5_11                      matlab.ui.control.Image
        Image5_10                      matlab.ui.control.Image
        Image5_8                       matlab.ui.control.Image
        Image5_7                       matlab.ui.control.Image
        Image5_6                       matlab.ui.control.Image
        Image5_5                       matlab.ui.control.Image
        Image5_4                       matlab.ui.control.Image
        Image5_3                       matlab.ui.control.Image
        Image5_2                       matlab.ui.control.Image
        Image5                         matlab.ui.control.Image
        Image4                         matlab.ui.control.Image
        Image3                         matlab.ui.control.Image
        Image2                         matlab.ui.control.Image
        Image                          matlab.ui.control.Image
        SaveButton                     matlab.ui.control.Button
        ColorDropDown                  matlab.ui.control.DropDown
        ColorDropDownLabel             matlab.ui.control.Label
        FinalizeButton                 matlab.ui.control.Button
        BinarizationDropDown           matlab.ui.control.DropDown
        BinarizationDropDownLabel      matlab.ui.control.Label
        ThresholdValueLabel            matlab.ui.control.Label
        EditField                      matlab.ui.control.NumericEditField
        SelectBinarizationButton       matlab.ui.control.Button
        ProcessButton                  matlab.ui.control.Button
        SelectEdgeButton               matlab.ui.control.Button
        ControlStructureDropDown       matlab.ui.control.DropDown
        ControlStructureDropDownLabel  matlab.ui.control.Label
        OpenImageButton                matlab.ui.control.Button
        RightPanel                     matlab.ui.container.Panel
        UIAxesColor                    matlab.ui.control.UIAxes
        UIAxesEdge                     matlab.ui.control.UIAxes
        UIAxesIntensity                matlab.ui.control.UIAxes
        UIAxesSaturation               matlab.ui.control.UIAxes
        UIAxesHue                      matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        inputImage
        edgeImage 
        binarizedImage
        finalizedImage
        caseValue
        selectedOption
   
    end


    methods (Access = public)

        function binarizedImage = binarization(app, edgeImage,thresholdValue,caseValue)
            switch caseValue
                    case 'Basic Global Thresholding'
                      
                        doubleEdgeImage = double(edgeImage);
                        normalizedThreshold = thresholdValue / 255;
                        binaryImage = doubleEdgeImage >= normalizedThreshold;
                        binarizedImage = binaryImage;
             
                    case 'Optimal Global Thresholding'   
                        grayImage = uint8(app.edgeImage) * 255;
                        level = graythresh(grayImage);
                        binarizedImage = imbinarize(grayImage, level);
            end
        end  
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OpenImageButton
        function OpenImageButtonPushed(app, event)

                if ~isempty(app.UIAxesEdge.Children)
                    app.UIAxesEdge.Children.Visible = 'off';
                end
                if ~isempty(app.UIAxesColor.Children)
                    app.UIAxesColor.Children.Visible = 'off';
                end
                
                app.UIAxesEdge.Title.Visible = 'off';
                app.UIAxesColor.Title.Visible = 'off';

                app.SelectBinarizationButton.Enable = 'off';
                app.BinarizationDropDown.Enable = 'off';
                app.SelectEdgeButton.Enable = 'off';
                app.SaveButton.Enable = 'off';
                app.ProcessButton.Enable = 'off';
                app.FinalizeButton.Enable = 'off';
                app.EditField.Enable = 'off';
                
                app.EditField.Value = 1;
                
                app.edgeImage = [];
                app.binarizedImage = [];
                app.finalizedImage = [];

                
                openFileChoice = uiconfirm(app.UIFigure, 'Do you want to load an image?', 'Open Confirmation', ...
                    'Options', {'Yes', 'No'}, 'DefaultOption', 'Yes');
                
                switch openFileChoice
                    case 'Yes'
                        try
                            [filename, pathname] = uigetfile({'*.jpg;*.jpeg;*.png', 'Image Files (*.jpg, *.jpeg, *.png)'}, 'Select an Image File');
                            
                            if isequal(filename, 0) || isequal(pathname, 0)
                                disp('Load operation canceled.');
                                return;
                            end

                            progressDialog = uiprogressdlg(app.UIFigure, 'Title', 'Loading Image', 'Message', 'Image is being loaded...', 'Indeterminate', 'on', 'Cancelable', 'on');
                            imagePath = fullfile(pathname, filename);
                            app.inputImage = imread(imagePath);
                
                            if ~isnumeric(app.inputImage)
                                disp('Invalid image selected.');
                                return;
                            end
                                            
                            hsiImage = rgbtohsi(app.inputImage);
                            
                            hueChannel = hsiImage(:, :, 1);
                            saturationChannel = hsiImage(:, :, 2);
                            intensityChannel = hsiImage(:, :, 3);

                            normalizedHue = mynormalize(hueChannel);
                            normalizedSaturation = mynormalize(saturationChannel);
                            normalizedIntensity = mynormalize(intensityChannel);
                            
                            imshow(normalizedHue, 'Parent', app.UIAxesHue);
                            title(app.UIAxesHue, 'Normalized Hue Channel');
                            
                            imshow(normalizedSaturation, 'Parent', app.UIAxesSaturation);
                            title(app.UIAxesSaturation, 'Normalized Saturation Channel');
                            
                            imshow(normalizedIntensity, 'Parent', app.UIAxesIntensity);
                            title(app.UIAxesIntensity, 'Normalized Intensity Channel');
                            
                            app.inputImage=normalizedIntensity;
                            uialert(app.UIFigure, 'Image loaded successfully', 'Success', 'Icon', 'success');
                            app.SelectEdgeButton.Enable = 'on';
                        
                        catch ME
                            delete(progressDialog);
                            rethrow(ME);
                        end
                        delete(progressDialog);
                
                    case 'No'
                        disp('Load operation canceled.');
                end
        end

        % Button pushed function: SelectEdgeButton
        function SelectEdgeButtonPushed(app, event)
            app.selectedOption = app.ControlStructureDropDown.Value;

            switch app.selectedOption
                case 'Prewitt kernel with diagonal gradients'
                    app.edgeImage = myedge(app.inputImage,'Prewitt', false);
                    app.BinarizationDropDown.Enable = 'on';
                    app.SelectBinarizationButton.Enable = 'on';
                    app.ProcessButton.Enable = 'off';
                    app.StdEditField_2.Enable = 'off';
                    app.nEditField_3.Enable = 'off';               
                    
                case 'Prewitt kernel without diagonal gradients'
                    app.edgeImage = myedge(app.inputImage, 'Prewitt', true);
                    app.BinarizationDropDown.Enable = 'on';
                    app.SelectBinarizationButton.Enable = 'on';
                    app.ProcessButton.Enable = 'off';
                    app.StdEditField_2.Enable = 'off';
                    app.nEditField_3.Enable = 'off';

                case 'Sobel kernel with diagonal gradients'
                    app.edgeImage = myedge(app.inputImage, 'Sobel', false);
                    app.BinarizationDropDown.Enable = 'on';
                    app.SelectBinarizationButton.Enable = 'on';
                    app.ProcessButton.Enable = 'off';
                    app.StdEditField_2.Enable = 'off';
                    app.nEditField_3.Enable = 'off';

                case 'Sobel kernel without diagonal gradients'
                    app.edgeImage = myedge(app.inputImage,'Sobel', true);
                    app.BinarizationDropDown.Enable = 'on';
                    app.SelectBinarizationButton.Enable = 'on'; 
                    app.ProcessButton.Enable = 'off';
                    app.StdEditField_2.Enable = 'off';
                    app.nEditField_3.Enable = 'off';
                    
                case 'Kirsch kernel with diagonal gradients'
                    app.edgeImage = myedge(app.inputImage, 'Kirsch', false);
                    app.BinarizationDropDown.Enable = 'on';
                    app.SelectBinarizationButton.Enable = 'on';
                    app.StdEditField_2.Enable = 'off';
                    app.nEditField_3.Enable = 'off';

                case 'Kirsch kernel without diagonal gradients'
                    app.edgeImage = myedge(app.inputImage, 'Kirsch', true);
                    app.BinarizationDropDown.Enable = 'on';
                    app.SelectBinarizationButton.Enable = 'on';
                    app.ProcessButton.Enable = 'off';
                    app.StdEditField_2.Enable = 'off';
                    app.nEditField_3.Enable = 'off';

                case 'Scharr kernel without diagonal gradients'
                    app.edgeImage = myedge(app.inputImage, 'Scharr', true);
                    app.SelectBinarizationButton.Enable = 'on';
                    app.BinarizationDropDown.Enable = 'on';
                    app.ProcessButton.Enable = 'off';
                    app.StdEditField_2.Enable = 'off';
                    app.nEditField_3.Enable = 'off'; 
                    
                case 'Laplacian of Gaussian'
                    app.ProcessButton.Enable = 'on';
                    app.SelectBinarizationButton.Enable = 'off';
                    app.BinarizationDropDown.Enable = 'off';
                    app.StdEditField_2.Enable = 'on';
                    app.nEditField_3.Enable = 'on';
                    app.StdEditField_2.Value = 0.4;
                    app.nEditField_3.Value = 5;
                    app.edgeImage =app.inputImage;

                case 'Canny'
                    app.ProcessButton.Enable = 'on';
                    app.SelectBinarizationButton.Enable = 'off';
                    app.BinarizationDropDown.Enable = 'off';
                    app.StdEditField_2.Enable = 'on';
                    app.nEditField_3.Enable = 'on';
                    app.StdEditField_2.Value = 1;
                    app.nEditField_3.Value = 5;
                    app.edgeImage =app.inputImage;
                    
            otherwise
                error('Invalid option selected.');
            end
  
            
        end

        % Button pushed function: SelectBinarizationButton
        function SelectBinarizationButtonPushed(app, event)
            app.caseValue = app.BinarizationDropDown.Value;
            if strcmp(app.caseValue, 'Optimal Global Thresholding')
                app.EditField.Enable = 'off';
                app.EditField.Value = 1;
            end 
            if strcmp(app.caseValue, 'Basic Global Thresholding')
                app.EditField.Enable = 'on';
            end
            app.ProcessButton.Enable = 'on';

        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            if ~isempty(app.UIAxesEdge.Children)
                app.UIAxesEdge.Children.Visible = 'off';
            end

            thresholdValue = app.EditField.Value;

            if isempty(app.edgeImage)
                    errorMessage = 'Please select an edge detection option first.';
                    uialert(app.UIFigure, errorMessage, 'Error', 'Icon', 'error');
                    title(app.UIAxesEdge, '');
                    return;
            end
            if thresholdValue < 1 || thresholdValue > 254
                uialert(app.UIFigure, 'Please enter a threshold value between 1 and 254.', 'Error', 'Icon', 'error');
                title(app.UIAxesEdge, '');
                app.EditField.Value = 1;
                return;
            end
            

            switch app.selectedOption
                case 'Prewitt kernel with diagonal gradients'
                    app.binarizedImage=binarization(app,app.edgeImage,thresholdValue,app.caseValue);

                case 'Prewitt kernel without diagonal gradients'
                     app.binarizedImage=binarization(app,app.edgeImage,thresholdValue,app.caseValue);

                case 'Sobel kernel with diagonal gradients'
                     app.binarizedImage=binarization(app,app.edgeImage,thresholdValue,app.caseValue);
            
                case 'Sobel kernel without diagonal gradients'
                     app.binarizedImage=binarization(app,app.edgeImage,thresholdValue,app.caseValue);
                    
                case 'Kirsch kernel with diagonal gradients'
                     app.binarizedImage=binarization(app,app.edgeImage,thresholdValue,app.caseValue);

                case 'Kirsch kernel without diagonal gradients'
                     app.binarizedImage=binarization(app,app.edgeImage,thresholdValue,app.caseValue);
                    
                case 'Scharr kernel without diagonal gradients'
                     app.binarizedImage=binarization(app,app.edgeImage,thresholdValue,app.caseValue);
                 
                case 'Laplacian of Gaussian'
                    std = app.StdEditField_2.Value;
                    n = app.nEditField_3.Value;
                    threshold = 0.15;

                    if std <= 0 || n <= 0
                        uialert(app.UIFigure, 'Please enter positive kernel size and standard deviation.', 'Error', 'Icon', 'error');
                        title(app.UIAxesEdge, '');
                        return;     
                    elseif std < 0 || std > 1
                        uialert(app.UIFigure, 'Please enter standard deviation between 0 and 1.', 'Error', 'Icon', 'error');
                        title(app.UIAxesEdge, '');
                        return;
                    elseif mod(n, 2) == 0
                        uialert(app.UIFigure, 'Kernel size should be an odd number. Please enter an odd number.', 'Error', 'Icon', 'error');
                        title(app.UIAxesEdge, '');
                        return;
                    end

                    log_kernel = fspecial('log', n, std);
                    app.edgeImage = imfilter(app.inputImage, log_kernel, 'replicate');
                    app.edgeImage = app.edgeImage > threshold; 
                    app.edgeImage = cat(3, app.edgeImage, app.edgeImage, app.edgeImage);
                    app.edgeImage = uint8(app.edgeImage) * 255;
                    app.binarizedImage=app.edgeImage;

                case 'Canny'   
                    lowThreshold = 0.2;
                    highThreshold = 0.3; 
                    std = app.StdEditField_2.Value;
                    n = app.nEditField_3.Value;
                    if std <= 0 || n <= 0
                        uialert(app.UIFigure, 'Please enter positive kernel size and standard deviation.', 'Error', 'Icon', 'error');
                        title(app.UIAxesEdge, '');
                        return;     
                    elseif std < 0 || std > 1
                        uialert(app.UIFigure, 'Please enter standard deviation between 0 and 1.', 'Error', 'Icon', 'error');
                        title(app.UIAxesEdge, '');
                        return;
                    elseif mod(n, 2) == 0
                        uialert(app.UIFigure, 'Kernel size should be an odd number. Please enter an odd number.', 'Error', 'Icon', 'error');
                        title(app.UIAxesEdge, '');
                        return;
                    end
                    app.edgeImage = mycanny(app.inputImage, lowThreshold, highThreshold, n, std);
                    app.binarizedImage = app.edgeImage;


            end
            app.SaveButton.Enable = 'on';
            app.FinalizeButton.Enable = 'on';
            imshow(app.binarizedImage, 'Parent', app.UIAxesEdge);
            title(app.UIAxesEdge, 'Binarized Image');
        end

        % Button pushed function: FinalizeButton
        function FinalizeButtonPushed(app, event)
                if ~isempty(app.UIAxesColor.Children)
                    app.UIAxesColor.Children.Visible = 'off';
                end
                app.UIAxesColor.Title.Visible = 'on';
 
                if isempty(app.binarizedImage)
                    uialert(app.UIFigure, 'Please perform binarization before finalizing.', 'Error', 'Icon', 'error');
                    title(app.UIAxesColor, '');
                    return;
                end
            
                selectedColormap = app.ColorDropDown.Value;

                try
                    
                    app.finalizedImage = mymorph(app.binarizedImage, selectedColormap);
                    
                    if ~isvalid(app.UIAxesColor.Parent)
                        figure('Name', 'Colored Output Image');
                    end
                    
                    app.SaveButton.Enable = 'on';
                    imshow(app.finalizedImage, 'Parent', app.UIAxesColor);
                    title(app.UIAxesColor, 'Colored Output Image');
                    uialert(app.UIFigure, 'Process completed successfully', 'Success', 'Icon', 'success');
                catch 
                    uialert(app.UIFigure, 'Error during finalization', 'Error', 'Icon', 'error');
                    title(app.UIAxesColor, '');
                end


        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)

            if ~isempty(app.binarizedImage) || ~isempty(app.finalizedImage)
                promptMessage = 'Do you want to save the images?';
                title = 'Save Confirmation';
                buttonLabels = {'Yes', 'No'};
                defaultButton = 'No';
            
                choice = uiconfirm(app.UIFigure, promptMessage, title, ...
                    'Options', buttonLabels, 'DefaultOption', defaultButton);

                if strcmp(choice, 'Yes')              
                    if ~isempty(app.finalizedImage)
                        imageToSave = app.finalizedImage;
                        saveDialogTitle = 'Save Finalized Image';
                    elseif ~isempty(app.binarizedImage)
                        imageToSave = app.binarizedImage;
                        saveDialogTitle = 'Save Binarized Image';
                    else
                        disp('No valid image selected.');
                        return; 
                    end
            
                    [filename, filepath] = uiputfile('*.ppm', saveDialogTitle);

                    if isequal(filename, 0) || isequal(filepath, 0)
                        disp('User canceled the operation.');
                    else
                        if ~isempty(imageToSave)
                            filePath = fullfile(filepath, filename);
                            imwrite(imageToSave, filePath, 'ppm');
                            uialert(app.UIFigure,'Image saved successfully.', 'Success', 'Icon', 'success');        
                            disp(['Image saved as ', filePath]);
                        else
                            disp('Invalid image data.');
                        end
                    end
                else
                    disp('Images not saved.');
                end
            else
                disp('No valid images to save.');
            end

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {882, 882};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {389, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.OpenImageButton = uibutton(app.LeftPanel, 'push');
            app.OpenImageButton.ButtonPushedFcn = createCallbackFcn(app, @OpenImageButtonPushed, true);
            app.OpenImageButton.Text = 'Open Image';
            app.ControlStructureDropDownLabel = uilabel(app.LeftPanel);
            app.ControlStructureDropDownLabel.Text = 'Control Structure';
            app.ControlStructureDropDown = uidropdown(app.LeftPanel);
            app.ControlStructureDropDown.Items = {'Prewitt kernel with diagonal gradients', 'Prewitt kernel without diagonal gradients', 'Sobel kernel with diagonal gradients', 'Sobel kernel without diagonal gradients', 'Kirsch kernel with diagonal gradients', 'Kirsch kernel without diagonal gradients', 'Scharr kernel without diagonal gradients', 'Laplacian of Gaussian', 'Canny'};
            app.ControlStructureDropDown.Value = 'Prewitt kernel with diagonal gradients';
            app.SelectEdgeButton = uibutton(app.LeftPanel, 'push');
            app.SelectEdgeButton.ButtonPushedFcn = createCallbackFcn(app, @SelectEdgeButtonPushed, true);
            app.SelectEdgeButton.Enable = 'off';
            app.SelectEdgeButton.Text = 'Select Edge';
            app.ProcessButton = uibutton(app.LeftPanel, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Enable = 'off';
            app.ProcessButton.Text = 'Process';
            app.SelectBinarizationButton = uibutton(app.LeftPanel, 'push');
            app.SelectBinarizationButton.ButtonPushedFcn = createCallbackFcn(app, @SelectBinarizationButtonPushed, true);
            app.SelectBinarizationButton.Enable = 'off';
            app.SelectBinarizationButton.Text = 'Select Binarization';
            app.EditField = uieditfield(app.LeftPanel, 'numeric');
            app.ThresholdValueLabel = uilabel(app.LeftPanel);
            app.ThresholdValueLabel.Text = 'Threshold Value';
            app.BinarizationDropDownLabel = uilabel(app.LeftPanel);
            app.BinarizationDropDownLabel.Text = 'Binarization';
            app.BinarizationDropDown = uidropdown(app.LeftPanel);
            app.BinarizationDropDown.Items = {'Basic Global Thresholding', 'Optimal Global Thresholding'};
            app.BinarizationDropDown.Value = 'Basic Global Thresholding';
            app.FinalizeButton = uibutton(app.LeftPanel, 'push');
            app.FinalizeButton.ButtonPushedFcn = createCallbackFcn(app, @FinalizeButtonPushed, true);
            app.FinalizeButton.Enable = 'off';
            app.FinalizeButton.Text = 'Finalize';
            app.ColorDropDownLabel = uilabel(app.LeftPanel);
            app.ColorDropDownLabel.Text = 'Color';
            app.ColorDropDown = uidropdown(app.LeftPanel);
            app.ColorDropDown.Items = {'Jet', 'Hot', 'Cool', 'Summer', 'Winter'};
            app.ColorDropDown.Value = 'Jet';
            app.SaveButton = uibutton(app.LeftPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save';
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = 'showsquare.jpeg';
            app.Image2 = uiimage(app.LeftPanel);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'zyro-image (1).png');
            app.Image3 = uiimage(app.LeftPanel);
            app.Image3.ImageSource = 'zyro-image (1).png';
            app.Image4 = uiimage(app.LeftPanel);
            app.Image4.ImageSource = 'zyro-image (1).png';
            app.Image5 = uiimage(app.LeftPanel);
            app.Image5.ImageSource = 'zyro-image (1).png';
            app.Image5_2 = uiimage(app.LeftPanel);
            app.Image5_2.ImageSource = 'zyro-image (1).png';
            app.Image5_3 = uiimage(app.LeftPanel);
            app.Image5_3.ImageSource = 'zyro-image (1).png';
            app.Image5_4 = uiimage(app.LeftPanel);
            app.Image5_4.ImageSource = 'zyro-image (1).png';
            app.Image5_5 = uiimage(app.LeftPanel);
            app.Image5_5.ImageSource = 'zyro-image (1).png';
            app.Image5_6 = uiimage(app.LeftPanel);
            app.Image5_6.ImageSource = 'zyro-image (1).png';
            app.Image5_7 = uiimage(app.LeftPanel);
            app.Image5_7.ImageSource = 'zyro-image (1).png';
            app.Image5_8 = uiimage(app.LeftPanel);
            app.Image5_8.ImageSource = 'zyro-image (1).png';
            app.Image5_10 = uiimage(app.LeftPanel);
            app.Image5_10.ImageSource = 'zyro-image (1).png';
            app.Image5_11 = uiimage(app.LeftPanel);
            app.Image5_11.ImageSource = 'zyro-image (1).png';
            app.Image5_12 = uiimage(app.LeftPanel);
            app.Image5_12.ImageSource = 'zyro-image (1).png';
            app.Image4_2 = uiimage(app.LeftPanel);
            app.Image4_2.ImageSource = 'zyro-image (1).png';
            app.Image2_2 = uiimage(app.LeftPanel);
            app.Image2_2.ImageSource = 'zyro-image (1).png';
            app.Image3_2 = uiimage(app.LeftPanel);
            app.Image3_2.ImageSource = 'zyro-image (1).png';
            app.Image2_4 = uiimage(app.LeftPanel);
            app.Image2_4.ImageSource = 'zyro-image (1).png';
            app.Image5_13 = uiimage(app.LeftPanel);
            app.Image5_13.ImageSource = 'zyro-image (1).png';
            app.Image5_14 = uiimage(app.LeftPanel);
            app.Image5_14.ImageSource = 'zyro-image (1).png';
            app.Image5_15 = uiimage(app.LeftPanel);
            app.Image5_15.ImageSource = 'zyro-image (1).png';
            app.Image5_16 = uiimage(app.LeftPanel);
            app.Image5_16.ImageSource = 'zyro-image (1).png';
            app.Image5_17 = uiimage(app.LeftPanel);
            app.Image5_17.ImageSource = 'zyro-image (1).png';
            app.Image5_18 = uiimage(app.LeftPanel);
            app.Image5_18.ImageSource = 'zyro-image (1).png';
            app.Image5_19 = uiimage(app.LeftPanel);
            app.Image5_19.ImageSource = 'zyro-image (1).png';
            app.Image5_20 = uiimage(app.LeftPanel);
            app.Image5_20.ImageSource = 'zyro-image (1).png';
            app.Image3_3 = uiimage(app.LeftPanel);
            app.Image3_3.ImageSource = 'zyro-image (1).png';
            app.Image5_21 = uiimage(app.LeftPanel);
            app.Image5_21.ImageSource = 'zyro-image (1).png';
            app.Image5_22 = uiimage(app.LeftPanel);
            app.Image5_22.ImageSource = 'zyro-image (1).png';
            app.Image5_23 = uiimage(app.LeftPanel);
            app.Image5_23.ImageSource = 'zyro-image (1).png';
            app.Image5_24 = uiimage(app.LeftPanel);
            app.Image5_24.ImageSource = 'zyro-image (1).png';
            app.Image5_25 = uiimage(app.LeftPanel);
            app.Image5_25.ImageSource = 'zyro-image (1).png';
            app.Image5_26 = uiimage(app.LeftPanel);
            app.Image5_26.ImageSource = 'zyro-image (1).png';
            app.Image5_27 = uiimage(app.LeftPanel);
            app.Image5_27.ImageSource = 'zyro-image (1).png';
            app.Image5_28 = uiimage(app.LeftPanel);
            app.Image5_28.ImageSource = 'zyro-image (1).png';
            app.Image4_3 = uiimage(app.LeftPanel);
            app.Image4_3.ImageSource = 'zyro-image (1).png';
            app.Image4_4 = uiimage(app.LeftPanel);
            app.Image4_4.ImageSource = 'zyro-image (1).png';
            app.Image3_4 = uiimage(app.LeftPanel);
            app.Image3_4.ImageSource = 'zyro-image (1).png';
            app.Image3_5 = uiimage(app.LeftPanel);
            app.Image3_5.ImageSource = 'zyro-image (1).png';
            app.nLabel = uilabel(app.LeftPanel);
            app.nLabel.Text = 'n';
            app.StdEditField_2 = uieditfield(app.LeftPanel, 'numeric');
            app.StdEditField_2.Enable = 'off';
            app.StdLabel = uilabel(app.LeftPanel);
            app.StdLabel.Text = 'Std';
            app.nEditField_3 = uieditfield(app.LeftPanel, 'numeric');
            app.nEditField_3.Enable = 'off';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxesHue = uiaxes(app.RightPanel);
            title(app.UIAxesHue, 'UIAxesHue')
            app.UIAxesHue.Visible = 'off';
            app.UIAxesSaturation = uiaxes(app.RightPanel);
            title(app.UIAxesSaturation, 'UIAxesSaturation')
            app.UIAxesSaturation.Visible = 'off';
            app.UIAxesIntensity = uiaxes(app.RightPanel);
            title(app.UIAxesIntensity, 'UIAxesIntensity')
            app.UIAxesIntensity.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxesIntensity.Visible = 'off';
            app.UIAxesEdge = uiaxes(app.RightPanel);
            title(app.UIAxesEdge, 'UIAxesEdge')
            app.UIAxesEdge.Visible = 'off';
            app.UIAxesColor = uiaxes(app.RightPanel);
            title(app.UIAxesColor, 'UIAxesColor')
            app.UIAxesColor.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Group9

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end